classdef CoinThresholdExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MainGrid         matlab.ui.container.GridLayout
        ControlGrid      matlab.ui.container.GridLayout
        ResetButton      matlab.ui.control.Button
        AutomaticButton  matlab.ui.control.Button
        ThresholdSlider  matlab.ui.control.Slider
        RuleLabel        matlab.ui.control.Label
        ThresholdLabel   matlab.ui.control.Label
        HistogramAxes    matlab.ui.control.UIAxes
        BinaryAxes       matlab.ui.control.UIAxes
        OriginalAxes     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        BinaryImage = ""
        GrayImage = ""
        ThresholdMarker = ""
    end

    methods (Access = private)

        function updateThreshold(app,threshold)
            % Slider and automatic threshold use the same 0-to-255 scale.
            app.ThresholdSlider.Value = threshold;
            app.BinaryImage.CData = imbinarize(app.GrayImage,threshold/255);
            app.ThresholdMarker.Value = threshold;
            app.ThresholdLabel.Text = sprintf('Threshold: %.1f',threshold);
            app.ThresholdMarker.Label = sprintf('Threshold: %.1f',threshold);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GrayImage = imread('coins.png');
            imshow(app.GrayImage,'Parent',app.OriginalAxes);
            title(app.OriginalAxes,'Original grayscale image','FontSize',18);
            app.BinaryImage = imshow(false(size(app.GrayImage)),'Parent',app.BinaryAxes);
            title(app.BinaryAxes,'Binary image','FontSize',18);
            histogram(app.HistogramAxes,app.GrayImage(:),'BinEdges',-0.5:1:255.5);
            title(app.HistogramAxes,'Grayscale intensity histogram','FontSize',18);
            xlabel(app.HistogramAxes,'Intensity (0 = black, 255 = white)');
            ylabel(app.HistogramAxes,'Pixel count');
            xlim(app.HistogramAxes,[-2 257]);
            app.HistogramAxes.XTick = [0 64 128 192 255];
            app.ThresholdMarker = xline(app.HistogramAxes,128,'--','Threshold','LineWidth',3,'FontSize',16);
            app.ThresholdMarker.LabelOrientation = 'horizontal';
            app.ThresholdMarker.LabelHorizontalAlignment = 'left';
            disableDefaultInteractivity(app.OriginalAxes);
            disableDefaultInteractivity(app.BinaryAxes);
            disableDefaultInteractivity(app.HistogramAxes);
            app.OriginalAxes.Toolbar = [];
            app.BinaryAxes.Toolbar = [];
            app.HistogramAxes.Toolbar = [];
            app.updateThreshold(128);
        end

        % Value changed function: ThresholdSlider
        function thresholdChanged(app, event)
            app.updateThreshold(round(app.ThresholdSlider.Value));
        end

        % Value changing function: ThresholdSlider
        function thresholdChanging(app, event)
            app.updateThreshold(round(event.Value));
        end

        % Button pushed function: AutomaticButton
        function automaticThreshold(app, event)
            app.updateThreshold(255*graythresh(app.GrayImage));
        end

        % Button pushed function: ResetButton
        function resetThreshold(app, event)
            app.updateThreshold(128);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'CoinThresholdExplorer';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.OriginalAxes = uiaxes(app.MainGrid);
            app.BinaryAxes = uiaxes(app.MainGrid);
            app.HistogramAxes = uiaxes(app.MainGrid);
            app.ControlGrid = uigridlayout(app.MainGrid);
            app.ThresholdLabel = uilabel(app.ControlGrid);
            app.ThresholdLabel.Text = 'Threshold: 128';
            app.RuleLabel = uilabel(app.ControlGrid);
            app.RuleLabel.Text = 'White = intensity > threshold';
            app.ThresholdSlider = uislider(app.ControlGrid);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.MajorTicks = [0 64 128 192 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdChanging, true);
            app.ThresholdSlider.MinorTicksMode = 'manual';
            app.ThresholdSlider.Value = 128;
            app.AutomaticButton = uibutton(app.ControlGrid, 'push');
            app.AutomaticButton.ButtonPushedFcn = createCallbackFcn(app, @automaticThreshold, true);
            app.AutomaticButton.Text = 'Automatic (Otsu)';
            app.ResetButton = uibutton(app.ControlGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetThreshold, true);
            app.ResetButton.Text = 'Reset: 128';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoinThresholdExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end